classdef MissionOCU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ControlLoopStatusLamp       matlab.ui.control.Lamp
        ControlLoopStatusLampLabel  matlab.ui.control.Label
        ControlLoopSwitch           matlab.ui.control.Switch
        ControlLoopSwitchLabel      matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        RobotPoseCheckBox           matlab.ui.control.CheckBox
        AprilTagPositionsCheckBox   matlab.ui.control.CheckBox
        GeoplotViewPanel            matlab.ui.container.Panel
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % creates two app.variable to hold april tag and robot position data
    properties (Access = private)
        tagPose = 0;
        robotPose = 0;
        myTime % how often the geoplot should update
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Geoplot(app)
            
            % GPS coordinates of the Oval (0,0) point
            origin_latlong = [42.29322828071904, -71.26433857013662, 50];
            gx = geoaxes(app.GeoplotViewPanel);
            geoplot(gx,origin_latlong(:,1),origin_latlong(:,2));

           hold on

           % plot poses
%            plot_pose_geoplot(tag_pose, origin_latlong, "r")   % plot tag pose
%            plot_pose_geoplot(robot_pose, origin_latlong, "b") % plot robot pose

            % geoplot styling
            geobasemap(gx,'satellite');
            % set the limits of the geoplot view
            latlim = [42.2931   42.2938];
            lonlim = [-71.2646  -71.2634];
            geolimits(gx,latlim, lonlim)
            legend("cam pose", "tag pose")

        end

        % Value changed function: ControlLoopSwitch
        function ControlLoopControlLoopSwitch(app, event)
            value = app.ControlLoopSwitch.Value;
            if value == "On"
                app.ControlLoopStatusLamp.Color=[0.00,1.00,0.00];
                %What else do we need to do to change lamp color?
            end
            if value == "Off"
                app.ControlLoopStatusLamp.Color=[1.00,0.00,0.00];
            end
        end

        % Value changed function: AprilTagPositionsCheckBox
        function AprilTagPositionsCheckBoxValueChanged(app, event)
            app.AprilTagPositionsCheckBox = app.AprilTagPositionsCheckBox.Value;
            %plot the april tag position           

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ControlLoopSwitchLabel = uilabel(app.LeftPanel);
            app.ControlLoopSwitchLabel.Text = 'Control Loop';
            app.ControlLoopSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ControlLoopSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlLoopControlLoopSwitch, true);
            app.ControlLoopStatusLampLabel = uilabel(app.LeftPanel);
            app.ControlLoopStatusLampLabel.Text = 'Control Loop Status';
            app.ControlLoopStatusLamp = uilamp(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GeoplotViewPanel = uipanel(app.CenterPanel);
            app.GeoplotViewPanel.Title = 'Geoplot View';
            app.AprilTagPositionsCheckBox = uicheckbox(app.CenterPanel);
            app.AprilTagPositionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AprilTagPositionsCheckBoxValueChanged, true);
            app.AprilTagPositionsCheckBox.Text = 'April Tag Positions';
            app.RobotPoseCheckBox = uicheckbox(app.CenterPanel);
            app.RobotPoseCheckBox.Text = 'Robot Pose';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MissionOCU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Geoplot)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end